classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ProcessButton_2              matlab.ui.control.Button
        TextInputTextArea            matlab.ui.control.TextArea
        TextInputTextAreaLabel       matlab.ui.control.Label
        WebCamButton                 matlab.ui.control.Button
        CropImageButton              matlab.ui.control.Button
        ProcessButton                matlab.ui.control.Button
        OuputTextArea                matlab.ui.control.TextArea
        OuputTextAreaLabel           matlab.ui.control.Label
        TexttoSpeechConversionLabel  matlab.ui.control.Label
        MultipleFilesButton          matlab.ui.control.Button
        BrowseButton                 matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       res ;
      oc % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseButton, OuputTextArea
        function BrowseButtonPushed(app, event)
        %             Use app.Component.Property to access a property:
        % val = app.Slider.Value; % Get
        % app.Gauge.Value = val; % Set
           
    [filename, pathname, ~] = uigetfile( ...
       {'*.m;*.fig;*.mat;*.mdl', 'All MATLAB Files (*.m, *.fig, *.mat, *.mdl)';
        '*.m',  'MATLAB Code (*.m)'; ...
        '*.fig','Figures (*.fig)'; ...
        '*.mat','MAT-files (*.mat)'; ...
        '*.mdl','Models (*.mdl)'; ...
        '*.*',  'All Files (*.*)'}, ...
        'Pick a file');
    if isequal(filename,0) || isequal(pathname,0)
        warndlg('User pressed Cancel', 'User pressed cancel');
    else
        app.oc = strcat(pathname,filename);
        app.oc = imread(app.oc);
        results = ocr(app.oc);
        app.res = results.Text;
        imshow(app.oc,'Parent',app.UIAxes);
        app.OuputTextArea.Value = app.res;


        %  userPrompt = 'What do you want the computer to say?';
        %  titleBar = 'Text to Speech';
        % defaultString = 'Hello World!  MATLAB is an awesome program!';
        %caUserInput = inputdlg(userPrompt, titleBar, 1, {res});
        % if isempty(caUserInput)
        %   return;
        % end; % Bail out if they clicked Cancel.
        % caUserInput = char(caUserInput); % Convert from cell to string.


    end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)

          a = app.res;
          B = string(a);
        NET.addAssembly('System.Speech');
        obj = System.Speech.Synthesis.SpeechSynthesizer;
        obj.Volume = 100;
        Speak(obj, B);
            
        end

        % Button pushed function: MultipleFilesButton
        function MultipleFilesButtonPushed(app, event)
            [filename, pathname, ~] = uigetfile( ...
                {'*.mat','MAT-files (*.mat)'; ...
                '*.mdl','Models (*.mdl)'; ...
                '*.*',  'All Files (*.*)'}, ...
                'Pick a file', ...
                'MultiSelect', 'on');
            
            if isequal(filename,0) || isequal(pathname,0)
                warndlg('User pressed Cancel', 'User pressed cancel');
            else
                n = numel(filename);
                for i = 1:n
                    a=filename{i};
                    b = strcat(pathname,a);
                    C=imread(b);
                    imshow(a)
                    results = ocr(C);
                    mul_res = results.Text;
                    imshow(C,'Parent',app.UIAxes);
                    app.OuputTextArea.Value = mul_res;
                    pause(7);
        
                    NET.addAssembly('System.Speech');
                    obj = System.Speech.Synthesis.SpeechSynthesizer;
                    obj.Volume = 100;
                    Speak(obj, mul_res);
                end
            end
            
        end

        % Button pushed function: CropImageButton
        function CropImageButtonPushed(app, event)
     
          [D, ~] = imcrop(app.oc);
          results = ocr(D);
          disp(results)
          crop_res = results.Text;
          disp(crop_res)
          imshow(D,'Parent',app.UIAxes);
          app.OuputTextArea.Value = crop_res;
          pause(7);

          NET.addAssembly('System.Speech');
          %This example shows you how to make .NET classes visible to MATLAB® by loading a global assembly using the NET.addAssembly function.
          %The speech synthesizer class (available in .NET Framework Version 3.0 and above) provides ready-to-use text-to-speech features.
          obj = System.Speech.Synthesis.SpeechSynthesizer;
          obj.Volume = 100;
          Speak(obj, crop_res);
     
        end

        % Button pushed function: WebCamButton
        function WebCamButtonPushed(app, event)
            app3
        end

        % Button pushed function: ProcessButton_2
        function ProcessButton_2Pushed(app, event)
                value = app.TextInputTextArea.Value;
                value = char(value);
                if isempty(value)
                    warndlg('Input Text Area is Empty', 'Please Enter Text');
                else
                    NET.addAssembly('System.Speech');
                    obj = System.Speech.Synthesis.SpeechSynthesizer;
                    obj.Volume = 100;
                    Speak(obj, value);

                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'OCR Image'; ''})
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.MultipleFilesButton = uibutton(app.UIFigure, 'push');
            app.MultipleFilesButton.ButtonPushedFcn = createCallbackFcn(app, @MultipleFilesButtonPushed, true);
            app.MultipleFilesButton.Text = 'Multiple Files';
            app.TexttoSpeechConversionLabel = uilabel(app.UIFigure);
            app.TexttoSpeechConversionLabel.Text = 'Text to Speech Conversion';
            app.OuputTextAreaLabel = uilabel(app.UIFigure);
            app.OuputTextAreaLabel.Text = 'Ouput';
            app.OuputTextArea = uitextarea(app.UIFigure);
            app.OuputTextArea.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.OuputTextArea.Value = {'You can see the Text in OCR here.'; ''};
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.CropImageButton = uibutton(app.UIFigure, 'push');
            app.CropImageButton.ButtonPushedFcn = createCallbackFcn(app, @CropImageButtonPushed, true);
            app.CropImageButton.Text = 'CropImage';
            app.WebCamButton = uibutton(app.UIFigure, 'push');
            app.WebCamButton.ButtonPushedFcn = createCallbackFcn(app, @WebCamButtonPushed, true);
            app.WebCamButton.Text = 'WebCam';
            app.TextInputTextAreaLabel = uilabel(app.UIFigure);
            app.TextInputTextAreaLabel.Text = 'Text Input';
            app.TextInputTextArea = uitextarea(app.UIFigure);
            app.ProcessButton_2 = uibutton(app.UIFigure, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_2Pushed, true);
            app.ProcessButton_2.Text = 'Process';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end