classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        SnapButton       matlab.ui.control.Button
        ProcessButton_2  matlab.ui.control.Button
        TextAreaLabel    matlab.ui.control.Label
        TextArea         matlab.ui.control.TextArea
        AgainButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        webcamObject;
        imageObject;
       webimage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Construct a webcam object
      
        app.webcamObject = webcam;
        app.imageObject = image(app.UIAxes);
        axis(app.UIAxes,'ij')
        res = split(app.webcamObject.Resolution,'x');
        app.UIAxes.XLim = [0,str2double(res{1})];
        app.UIAxes.YLim = [0,str2double(res{2})];
        app.webcamObject.preview(app.imageObject);
       
           
%         camObj = webcam;
%         preview(camObj)
%         im = image(app.UIAxes,zeros(size(snapshot(camObj)),'uint8'));
%         preview(camObj,im)


        % Preview a stream of image frames.
        
 
        % Acquire and display a single image frame.
%         img = snapshot(camObj);
%         imshow(img);
        end

        % Button pushed function: SnapButton
        function SnapButtonPushed(app, event)
        app.webimage = snapshot(app.webcamObject);
        imshow(app.webimage)
        end

        % Button pushed function: ProcessButton_2
        function ProcessButton_2Pushed(app, event)
             
            % hObject    handle to startcamerabutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
               
        results = ocr(app.webimage);
        res = results.Text;
        imshow(app.webimage,'Parent',app.UIAxes);
        app.TextArea.Value = res;
        
        NET.addAssembly('System.Speech');
        obj = System.Speech.Synthesis.SpeechSynthesizer;
        obj.Volume = 100;
        Speak(obj, res);
       
        end

        % Button pushed function: AgainButton
        function AgainButtonPushed(app, event)
            app.webcamObject = webcam;
        app.imageObject = image(app.UIAxes);
        axis(app.UIAxes,'ij')
        res = split(app.webcamObject.Resolution,'x');
        app.UIAxes.XLim = [0,str2double(res{1})];
        app.UIAxes.YLim = [0,str2double(res{2})];
        app.webcamObject.preview(app.imageObject);
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SnapButton = uibutton(app.UIFigure, 'push');
            app.SnapButton.ButtonPushedFcn = createCallbackFcn(app, @SnapButtonPushed, true);
            app.SnapButton.Text = {'Snap'; ''};
            app.ProcessButton_2 = uibutton(app.UIFigure, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_2Pushed, true);
            app.ProcessButton_2.Text = 'Process';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.AgainButton = uibutton(app.UIFigure, 'push');
            app.AgainButton.ButtonPushedFcn = createCallbackFcn(app, @AgainButtonPushed, true);
            app.AgainButton.Text = 'Again';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end